classdef Final_Project_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RightIntSpinner                 matlab.ui.control.Spinner
        RightIntSpinnerLabel            matlab.ui.control.Label
        LeftIntSpinner                  matlab.ui.control.Spinner
        LeftIntSpinnerLabel             matlab.ui.control.Label
        GraphButton                     matlab.ui.control.Button
        IterationsTextArea              matlab.ui.control.TextArea
        IterationsTextAreaLabel         matlab.ui.control.Label
        MaximumIterationEditField       matlab.ui.control.EditField
        MaximumIterationEditFieldLabel  matlab.ui.control.Label
        EquationEditField               matlab.ui.control.EditField
        EquationEditFieldLabel          matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ErrorTextArea                   matlab.ui.control.TextArea
        ErrorTextAreaLabel              matlab.ui.control.Label
        RootTextArea                    matlab.ui.control.TextArea
        RootTextAreaLabel               matlab.ui.control.Label
        EstimationEditField             matlab.ui.control.NumericEditField
        EstimationEditFieldLabel        matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        x1EditField                     matlab.ui.control.NumericEditField
        x1EditFieldLabel                matlab.ui.control.Label
        x0EditField                     matlab.ui.control.NumericEditField
        x0EditFieldLabel                matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        LowerBoundEditField             matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel        matlab.ui.control.Label
        UpperBoundEditField             matlab.ui.control.NumericEditField
        UpperBoundEditFieldLabel        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ToleranceThresholdEditField     matlab.ui.control.NumericEditField
        ToleranceThresholdEditFieldLabel  matlab.ui.control.Label
        ChooseErrorCalculationButtonGroup  matlab.ui.container.ButtonGroup
        TrueAbsoluteErrorButton         matlab.ui.control.RadioButton
        AbsoluteRelativeApproximateErrorButton  matlab.ui.control.RadioButton
        AbsoluteApproximateErrorButton  matlab.ui.control.RadioButton
        ChooseMethodButtonGroup         matlab.ui.container.ButtonGroup
        NewtonMethodButton_2            matlab.ui.control.RadioButton
        SecantMethodButton_2            matlab.ui.control.RadioButton
        FalsePositionMethodButton_2     matlab.ui.control.RadioButton
        BisectionMethodButton           matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: ChooseMethodButtonGroup
        function ChooseMethodButtonGroupButtonDown(app, event)
            selectedMethod = app.ChooseMethodButtonGroup.SelectedObject;
            switch selectedMethod.Text
                case 'Bisection Method'
                    chosen_method = 1;
                case 'False Position Method'
                    chosen_method = 2;
                case 'Secant Method'
                    chosen_method = 3;
                case 'Newton Method'
                    chosen_method = 4;
            end
        end

        % Value changed function: EquationEditField
        function EquationEditFieldValueChanged(app, event)
            value = app.EquationEditField.Value;
            var = '@(x)';
            eq = strcat(var, value);
            fn = str2func(eq);
        end

        % Value changed function: ToleranceThresholdEditField
        function ToleranceThresholdEditFieldValueChanged(app, event)
            es_or_iter = app.ToleranceThresholdEditField.Value;

        end

        % Value changed function: UpperBoundEditField
        function UpperBoundEditFieldValueChanged(app, event)
            value = app.UpperBoundEditField.Value;
            
        end

        % Value changed function: RootTextArea
        function RootTextAreaValueChanged(app, event)
            value = app.RootTextArea.Value;
            
        end

        % Value changed function: LowerBoundEditField
        function LowerBoundEditFieldValueChanged(app, event)
            value = app.LowerBoundEditField.Value;
            
        end

        % Selection changed function: 
        % ChooseErrorCalculationButtonGroup
        function ChooseErrorCalculationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseErrorCalculationButtonGroup.SelectedObject;
            switch selectedButton.Text;
                case 'True Error'
                    stop_crit = 1;
                case 'Iteration'
                    stop_crit = 2;
            end
        end

        % Value changed function: EstimationEditField
        function EstimationEditFieldValueChanged(app, event)
            value = app.EstimationEditField.Value;
            
        end

        % Value changed function: x0EditField
        function x0EditFieldValueChanged(app, event)
            value = app.x0EditField.Value;
            
        end

        % Value changed function: x1EditField
        function x1EditFieldValueChanged(app, event)
            value = app.x1EditField.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %VARIABLE DECLARATION
            
             %function input
            value = app.EquationEditField.Value;
            func = str2func(['@(x)',value]);
            
            %Switch case to determine Method
            selectedMethod = app.ChooseMethodButtonGroup.SelectedObject;
            switch selectedMethod.Text
                case 'Bisection Method'
                    chosen_method = 1;
                case 'False Position Method'
                    chosen_method = 2;
                case 'Secant Method'
                    chosen_method = 3;
                case 'Newton Method'
                    chosen_method = 4;
            end
            
            %Choose Error Type
            selectedStop = app.ChooseErrorCalculationButtonGroup.SelectedObject;
            switch selectedStop.Text
                case 'Absolute Approximate Error'
                    err = 1;
                case 'Absolute Relative Approximate Error'
                    err = 2;
                case 'True Absolute Error'
                    err = 3;
            end
            
            tolerance = app.ToleranceThresholdEditField.Value;
            maxit = app.MaximumIterationEditField.Value;

           %Bounds/Intervals/Estimation
            xl = app.LowerBoundEditField.Value;
            xu = app.UpperBoundEditField.Value;
            
            x0 = app.x0EditField.Value;
            x1 = app.x1EditField.Value;
            
            estimate = app.EstimationEditField.Value;
            
            
            %Calling different Method functions
            if chosen_method == 1
                 [root, ea, iter] = Project_Bisection(@(x) func(x),xl,xu,err,tolerance,maxit);
                 app.RootTextArea.Value = num2str(root);
                 app.ErrorTextArea.Value = num2str(ea);
                 app.IterationsTextArea.Value = num2str(iter);
            elseif chosen_method == 2
                [root,ea,iter] = Project_False_Position(@(x) func(x),xl,xu,err,tolerance,maxit);
                app.RootTextArea.Value = num2str(root);
                app.ErrorTextArea.Value = num2str(ea);
                app.IterationsTextArea.Value = num2str(iter);
            elseif chosen_method == 3
                [root,ea,iter] = Project_Secant(@(x) func(x),x0,x1,err,tolerance,maxit);
                app.RootTextArea.Value = num2str(root);
                app.ErrorTextArea.Value = num2str(ea);
                app.IterationsTextArea.Value = num2str(iter);
            elseif chosen_method == 4
                syms x 
                f = func;
                dfunc = eval(['@(x)' char(diff(f(x)))]);
                [root,ea,iter] = Project_Newtraph(@(x) func(x),@(x) dfunc(x),estimate,err,tolerance,maxit);
                app.RootTextArea.Value = num2str(root);
                app.ErrorTextArea.Value = num2str(ea);
                app.IterationsTextArea.Value = num2str(iter);
            end


        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            %VARIABLE DECLARATION
            
            %function input
            value = app.EquationEditField.Value;
            y = str2func(['@(x)',value]);
            xl = app.LeftIntSpinner.Value;
            xr = app.RightIntSpinner.Value;
            fplot(y,[xl xr])
            title(value)
            xlabel x
            ylabel y
            grid on
            
        end

        % Value changed function: LeftIntSpinner
        function LeftIntSpinnerValueChanged(app, event)
            value = app.LeftIntSpinner.Value;
            
        end

        % Value changed function: RightIntSpinner
        function RightIntSpinnerValueChanged(app, event)
            value = app.RightIntSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseMethodButtonGroup.Title = 'Choose Method';
            app.ChooseMethodButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ChooseMethodButtonGroupButtonDown, true);
            app.BisectionMethodButton = uiradiobutton(app.ChooseMethodButtonGroup);
            app.BisectionMethodButton.Text = 'Bisection Method';
            app.BisectionMethodButton.Value = true;
            app.FalsePositionMethodButton_2 = uiradiobutton(app.ChooseMethodButtonGroup);
            app.FalsePositionMethodButton_2.Text = 'False Position Method';
            app.SecantMethodButton_2 = uiradiobutton(app.ChooseMethodButtonGroup);
            app.SecantMethodButton_2.Text = 'Secant Method';
            app.NewtonMethodButton_2 = uiradiobutton(app.ChooseMethodButtonGroup);
            app.NewtonMethodButton_2.Text = 'Newton Method';
            app.ChooseErrorCalculationButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseErrorCalculationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseErrorCalculationButtonGroupSelectionChanged, true);
            app.ChooseErrorCalculationButtonGroup.Title = 'Choose Error Calculation';
            app.AbsoluteApproximateErrorButton = uiradiobutton(app.ChooseErrorCalculationButtonGroup);
            app.AbsoluteApproximateErrorButton.Text = 'Absolute Approximate Error';
            app.AbsoluteApproximateErrorButton.Value = true;
            app.AbsoluteRelativeApproximateErrorButton = uiradiobutton(app.ChooseErrorCalculationButtonGroup);
            app.AbsoluteRelativeApproximateErrorButton.Text = 'Absolute Relative Approximate Error';
            app.TrueAbsoluteErrorButton = uiradiobutton(app.ChooseErrorCalculationButtonGroup);
            app.TrueAbsoluteErrorButton.Text = 'True Absolute Error';
            app.ToleranceThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranceThresholdEditFieldLabel.Text = 'Tolerance Threshold';
            app.ToleranceThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranceThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceThresholdEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'For Bisection and False Position Methods:'};
            app.UpperBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperBoundEditField.ValueChangedFcn = createCallbackFcn(app, @UpperBoundEditFieldValueChanged, true);
            app.LowerBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.LowerBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerBoundEditField.ValueChangedFcn = createCallbackFcn(app, @LowerBoundEditFieldValueChanged, true);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'For Secant Method:'};
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @x0EditFieldValueChanged, true);
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField.ValueChangedFcn = createCallbackFcn(app, @x1EditFieldValueChanged, true);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'For Newton Method:'};
            app.EstimationEditFieldLabel = uilabel(app.UIFigure);
            app.EstimationEditFieldLabel.Text = 'Estimation';
            app.EstimationEditField = uieditfield(app.UIFigure, 'numeric');
            app.EstimationEditField.ValueChangedFcn = createCallbackFcn(app, @EstimationEditFieldValueChanged, true);
            app.RootTextAreaLabel = uilabel(app.UIFigure);
            app.RootTextAreaLabel.Text = 'Root';
            app.RootTextArea = uitextarea(app.UIFigure);
            app.RootTextArea.ValueChangedFcn = createCallbackFcn(app, @RootTextAreaValueChanged, true);
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Text = 'Error%';
            app.ErrorTextArea = uitextarea(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EquationEditFieldLabel = uilabel(app.UIFigure);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.EquationEditField.ValueChangedFcn = createCallbackFcn(app, @EquationEditFieldValueChanged, true);
            app.MaximumIterationEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumIterationEditFieldLabel.Text = 'Maximum Iteration';
            app.MaximumIterationEditField = uieditfield(app.UIFigure, 'text');
            app.IterationsTextAreaLabel = uilabel(app.UIFigure);
            app.IterationsTextAreaLabel.Text = 'Iterations';
            app.IterationsTextArea = uitextarea(app.UIFigure);
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.LeftIntSpinnerLabel = uilabel(app.UIFigure);
            app.LeftIntSpinnerLabel.Text = 'Left Int';
            app.LeftIntSpinner = uispinner(app.UIFigure);
            app.LeftIntSpinner.ValueChangedFcn = createCallbackFcn(app, @LeftIntSpinnerValueChanged, true);
            app.RightIntSpinnerLabel = uilabel(app.UIFigure);
            app.RightIntSpinnerLabel.Text = 'Right Int';
            app.RightIntSpinner = uispinner(app.UIFigure);
            app.RightIntSpinner.ValueChangedFcn = createCallbackFcn(app, @RightIntSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end